classdef beta < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        DETEKSIBANYAKOBJEKPanel    matlab.ui.container.Panel
        JumlahObjekEditField       matlab.ui.control.NumericEditField
        JumlahObjekEditFieldLabel  matlab.ui.control.Label
        ResetButton                matlab.ui.control.Button
        ResultButton               matlab.ui.control.Button
        BrowseButton               matlab.ui.control.Button
        UIAxes_4                   matlab.ui.control.UIAxes
        UIAxes_3                   matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
        UIAxes_1                   matlab.ui.control.UIAxes
    end


    methods (Access = public)

        function browse(app)
            [name_file1,name_path1] = uigetfile( ...
                {'*.bmp;*.jpg;*.tif','Files of type (*.bmp,*.jpg,*.tif)';
                '*.bmp','File Bitmap (*.bmp)';...
                '*.jpg','File jpeg (*.jpg)';
                '*.tif','File Tif (*.tif)';
                '*.*','All Files (*.*)'},...
                'Open Image');


            filename=strcat(name_path1,name_file1);
            datao = filename;
            global data1
            data1 = filename;

            imshow(datao,'Parent',app.UIAxes_1);
        end


        function results(app)
            global data1
            % Grayscale
            Iinitial = imread(data1);
            [x, y, ~]=size(Iinitial);
            [~, ~, numberOfColorChannels] = size(Iinitial);
            if numberOfColorChannels > 1
                % It's a true color RGB image.  We need to convert to gray scale.
                Igray = rgb2gray(Iinitial);
            else
                % It's already gray scale.  No need to convert.
                Igray = Iinitial;
            end
            imshow(Igray,'Parent',app.UIAxes_2);

            % Biner
            biner=double(Igray);
            sum=0;
            for i=1:x
                for j=1:y
                    sum=sum+biner(i, j);
                end
            end
            threshold=sum/(x*y);
            binary=zeros(x, y);

            for i=1:x
                for j=1:y
                    if biner(i, j) >= threshold
                        binary(i, j) = 1;
                    else
                        binary(i, j)=0;
                    end
                end

            end


            % BW
            BW = imbinarize( binary );
            invert = ~BW;


            % Deteksi Tepi

            I = invert;

            L = imclearborder(I);
            M = imfill(L,'holes');
            N = bwareaopen(M,100);


            imshow(N,'Parent',app.UIAxes_3);
            E_canny = edge(N,'canny');
            imshow(E_canny,'Parent',app.UIAxes_4);


            % Membuat Boundaries
            [B,~] = bwboundaries(E_canny,'noholes');

            hold(app.UIAxes_4,"on")


            for k = 1:length(B)
                boundary = B{k};

                plot(app.UIAxes_4,boundary(:,2), boundary(:,1), 'y', 'LineWidth', 2);
            end
            hold(app.UIAxes_4,"off")
            app.JumlahObjekEditField.Value = length(B);


        end


        function reset(app)
            app.JumlahObjekEditField.Value = 0;
            cla(app.UIAxes_1);
            cla(app.UIAxes_2);
            cla(app.UIAxes_3);
            cla(app.UIAxes_4);
        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BrowseButton
        function BrowseButtonPushed(app, event)
            browse(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            reset(app);
        end

        % Button pushed function: ResultButton
        function ResultButtonPushed(app, event)
            results(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_1 = uiaxes(app.UIFigure);
            app.UIAxes_1.XTick = [];
            app.UIAxes_1.YTick = [];
            app.UIAxes_2 = uiaxes(app.UIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.UIFigure);
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.UIFigure);
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.DETEKSIBANYAKOBJEKPanel = uipanel(app.UIFigure);
            app.DETEKSIBANYAKOBJEKPanel.Title = 'DETEKSI BANYAK OBJEK';
            app.BrowseButton = uibutton(app.DETEKSIBANYAKOBJEKPanel, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButtonPushed, true);
            app.BrowseButton.Text = 'Browse';
            app.ResultButton = uibutton(app.DETEKSIBANYAKOBJEKPanel, 'push');
            app.ResultButton.ButtonPushedFcn = createCallbackFcn(app, @ResultButtonPushed, true);
            app.ResultButton.Text = 'Result';
            app.ResetButton = uibutton(app.DETEKSIBANYAKOBJEKPanel, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.JumlahObjekEditFieldLabel = uilabel(app.DETEKSIBANYAKOBJEKPanel);
            app.JumlahObjekEditFieldLabel.Text = 'Jumlah Objek';
            app.JumlahObjekEditField = uieditfield(app.DETEKSIBANYAKOBJEKPanel, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = beta

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end